classdef MATKit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MATKitUIFigure    matlab.ui.Figure
        ImagePanel        matlab.ui.container.Panel
        SegmentButton     matlab.ui.control.Button
        IOToolsButton     matlab.ui.control.Button
        StructurePanel    matlab.ui.container.Panel
        StatisticsButton  matlab.ui.control.Button
        ChordsButton      matlab.ui.control.Button
        ObjectsButton     matlab.ui.control.Button
        GenerateButton    matlab.ui.control.Button
        AnalysisPanel     matlab.ui.container.Panel
        DRToolsButton     matlab.ui.control.Button
        FitterButton      matlab.ui.control.Button
    end


    properties (Access = private)
        % App List
        FitterApp = [];
        DRToolsApp = [];
        IOToolsApp = [];
        SegmenterApp = [];
        StatsApp = [];
        GeneratorApp = [];
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screensize = get( groot, 'Screensize' );
            app.MATKitUIFigure.Position(1) = screensize(3)/2 - app.MATKitUIFigure.Position(3)/2;
            app.MATKitUIFigure.Position(2) = screensize(4) - app.MATKitUIFigure.Position(4) - 30;
        end

        % Button pushed function: DRToolsButton
        function DRToolsButtonPushed(app, event)
            app.DRToolsApp = DRTools;
        end

        % Button pushed function: FitterButton
        function FitterButtonPushed(app, event)
            app.FitterApp = Fitter;
        end

        % Button pushed function: IOToolsButton
        function IOToolsButtonPushed(app, event)
            app.IOToolsApp = IOTools;
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
            app.SegmenterApp = Segmenter;
        end

        % Button pushed function: StatisticsButton
        function StatisticsButtonPushed(app, event)
            app.StatsApp = StatsTool;
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.GeneratorApp = Generator;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MATKitUIFigure = uifigure;
            app.MATKitUIFigure.Name = 'MATKit';
            app.ImagePanel = uipanel(app.MATKitUIFigure);
            app.ImagePanel.Title = 'Image';
            app.SegmentButton = uibutton(app.ImagePanel, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Icon = 'Segmentation.png';
            app.SegmentButton.Text = 'Segment';
            app.IOToolsButton = uibutton(app.ImagePanel, 'push');
            app.IOToolsButton.ButtonPushedFcn = createCallbackFcn(app, @IOToolsButtonPushed, true);
            app.IOToolsButton.Icon = 'IO.png';
            app.IOToolsButton.Text = 'IO Tools';
            app.StructurePanel = uipanel(app.MATKitUIFigure);
            app.StructurePanel.Title = 'Structure';
            app.StatisticsButton = uibutton(app.StructurePanel, 'push');
            app.StatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticsButtonPushed, true);
            app.StatisticsButton.Icon = 'Stats.png';
            app.StatisticsButton.Text = 'Statistics';
            app.ChordsButton = uibutton(app.StructurePanel, 'push');
            app.ChordsButton.Icon = 'Stats.png';
            app.ChordsButton.Enable = 'off';
            app.ChordsButton.Text = 'Chords';
            app.ObjectsButton = uibutton(app.StructurePanel, 'push');
            app.ObjectsButton.Icon = 'Stats.png';
            app.ObjectsButton.Enable = 'off';
            app.ObjectsButton.Text = 'Objects';
            app.GenerateButton = uibutton(app.StructurePanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Icon = 'Generator.PNG';
            app.GenerateButton.Text = 'Generate';
            app.AnalysisPanel = uipanel(app.MATKitUIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.DRToolsButton = uibutton(app.AnalysisPanel, 'push');
            app.DRToolsButton.ButtonPushedFcn = createCallbackFcn(app, @DRToolsButtonPushed, true);
            app.DRToolsButton.Icon = 'DRTools.png';
            app.DRToolsButton.Text = 'DR Tools';
            app.FitterButton = uibutton(app.AnalysisPanel, 'push');
            app.FitterButton.ButtonPushedFcn = createCallbackFcn(app, @FitterButtonPushed, true);
            app.FitterButton.Icon = 'Fitter.png';
            app.FitterButton.Text = 'Fitter';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MATKit

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MATKitUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MATKitUIFigure)
        end
    end
end